classdef RoomAcoustic_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        InstructionsPanel               matlab.ui.container.Panel
        SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_3  matlab.ui.control.Label
        SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_2  matlab.ui.control.Label
        SetdesiredsizeofenviornemntinConfigurepanelandclickLabel  matlab.ui.control.Label
        DirectionsLabel                 matlab.ui.control.Label
        ThisisanacousticroomsimulatorusingOpenAIRIRLabel  matlab.ui.control.Label
        WelcomePanel                    matlab.ui.container.Panel
        AcousticSimulatorLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ConfigureTab                    matlab.ui.container.Tab
        TailCutoffKnob                  matlab.ui.control.Knob
        TailCutoffKnobLabel             matlab.ui.control.Label
        ReflectionCoeffecientKnob       matlab.ui.control.Knob
        ReflectionCoeffecientKnobLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        SourceRecieverCoordinatesPanel  matlab.ui.container.Panel
        recYSpinner                     matlab.ui.control.Spinner
        YSpinner_2Label                 matlab.ui.control.Label
        recZSpinner                     matlab.ui.control.Spinner
        ZSpinner_2Label                 matlab.ui.control.Label
        recXSpinner                     matlab.ui.control.Spinner
        XSpinner_2Label                 matlab.ui.control.Label
        sourceZSpinner                  matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        sourceYSpinner                  matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        sourceXSpinner                  matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        RecieveroLabel                  matlab.ui.control.Label
        SourcexLabel                    matlab.ui.control.Label
        RoomSizeParametersPanel         matlab.ui.container.Panel
        HeightSlider                    matlab.ui.control.Slider
        HeightSliderLabel               matlab.ui.control.Label
        DepthSlider                     matlab.ui.control.Slider
        DepthSlider_2Label              matlab.ui.control.Label
        WidthSlider_2Label              matlab.ui.control.Label
        WidthSlider                     matlab.ui.control.Slider
        ParametersPanel                 matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SelectionTab                    matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        ChooseEnviornmentPanel          matlab.ui.container.Panel
        IRSelectDropDown                matlab.ui.control.DropDown
        IRSelectDropDownLabel           matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        SelectModeListBox               matlab.ui.control.ListBox
        SelectModeListBoxLabel          matlab.ui.control.Label
        envSelect                       matlab.ui.control.DropDown
        EnviornementSelectDropDownLabel  matlab.ui.control.Label
        IRgraph                         matlab.ui.control.UIAxes
        SimulationTab                   matlab.ui.container.Tab
        SimulatePanel                   matlab.ui.container.Panel
        ErrorLabel_IR                   matlab.ui.control.Label
        PlayAudioButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ApplyIRButton                   matlab.ui.control.Button
        OutputGraph                     matlab.ui.control.UIAxes
        LoadAudioFilePanel              matlab.ui.container.Panel
        PlaySample                      matlab.ui.control.Button
        LoadAudioButton                 matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dropdown = [] % Drop down options 
        
        all_options = ["1st Baptist Nashivlle","Elveden Hall Suffolk England",...
            "Hamilton-Mausoleum","Heslington-church","Maes-howe" ,"Falkland Palace Dungeon",...
            "Gill-heads-mine", "hoffmann-lime-kiln-langcliffeuk", "newgrange", "st-patricks-church-patrington",...
            "trollers-gill", "tyndall-bruce-monument","usina-del-arte-symphony-hall"];
        IR = [] % Impulse Repsonse
        audioFile = [] % Audio sample loaded
        filePath = '' % pathname
        applied_IR = [] % Final output with applied IR
        fs_file = 48000 % sampling frequency
      
        RIR = [] % Description
    end
    
    methods (Access = private)
        
        function returnOptions(app,Uwidth,Udepth,Uheight,U_sr_distance)
             % Return array of rooms based on configuration 
            options = []; %dropdown options array
            %database
    
            %1st Baptist Nashivlles
            nashville = struct();
            nashville.width = 15; %inmeters
            nashville.depth = 24;
            nashville.height = 15;
    
            %elveden-hall-suffolk-england
            elveden_hall = struct();
            elveden_hall.width = 20;
            elveden_hall.depth = 30;
            elveden_hall.height = 10;
    
            %Hamilton-Mausoleum
            hamilton = struct();
            hamilton.width = 30;
            hamilton.depth = 25;
            hamilton.height = 37;
                
            %heslington-church-vaa-group-2
            heslington = struct();
            heslington.width = 12;
            heslington.depth = 12;
            heslington.height = 12;
    
            %maes-howe
            maes_howe = struct();
            maes_howe.width = 12;
            maes_howe.depth = 12;
            maes_howe.height = 12;
            
            %falkland-palace-bottle-dungeon
            falkland_dungeon = struct();
            falkland_dungeon.width = 2;
            falkland_dungeon.depth = 2;
            falkland_dungeon.height = 2;

            %Gill-heads-mine
            gill_heads_mine = struct();
            gill_heads_mine.width = 2;
            gill_heads_mine.depth = 30;
            gill_heads_mine.height = 4;

            %hoffmann-lime
            hoffmann = struct();
            hoffmann.width = 8;
            hoffmann.depth = 20;
            hoffmann.height = 8;

            %newgrange
            newgrange = struct();
            newgrange.width = 2;
            newgrange.depth = 18;
            newgrange.height = 2;
            
            %st-patricks-church-patrington
            patrington = struct();
            patrington.width = 27;
            patrington.depth = 46;
            patrington.height = 57.5;

            %trollers-gill
            trollers_gill = struct();
            trollers_gill.width = 100;
            trollers_gill.depth = 40;
            trollers_gill.height = 100;

            %tyndall-bruce-monument 
            tyndall_bruce = struct();
            tyndall_bruce.width = 3.4;
            tyndall_bruce.depth = 3.4;
            tyndall_bruce.height = 3.4;

            %usina-del-arte-symphony-hall
            usina_del_arte = struct();
            usina_del_arte.width = 28;
            usina_del_arte.depth = 41;
            usina_del_arte.height = 13;

 
            %Main enviornment struct to hold nested structs
            env = struct();
            env.("nashville") = nashville;
            env.("elveden_hall") = elveden_hall;
            env.("hamilton") = hamilton;
            env.("heslington") = heslington;
            env.("maes_howe") = maes_howe;
            env.("falkland_dungeon") = falkland_dungeon;
            env.("gill_heads_mine") = gill_heads_mine;
            env.("hoffmann") = hoffmann;
            env.("newgrange") = newgrange;
            env.("patrington") = patrington;
            env.("trollers_gill") = trollers_gill;
            env.("tyndall_bruce") = tyndall_bruce;
            env.("usina_del_arte") = usina_del_arte;


            %Suggestion algorithm
            fn = fieldnames(env); %returns keys in database
            tol = 2.5; %tolerance

            for k=1:numel(fn)
                room = string(fn{k});
                isWidthWithinRange = (Uwidth >= env.(room).width - tol) && ...
                    (Uwidth <= env.(room).width + tol);
                isDepthWithinRange = (Udepth >= env.(room).depth - tol) && ...
                    (Udepth <= env.(room).depth + tol);
                isHeightWithinRange = (Uheight >= env.(room).height - tol) && ...
                    (Uheight <= env.(room).height + tol);
    
                if (isWidthWithinRange && isDepthWithinRange && isHeightWithinRange)
                    % check source/reciever distances
                    %arr = env.(room).srdist;
                    options = [options string(room)];
%                     for a=1:numel(arr)
%                         val = arr(a);
%                         isSRwithinRange = (U_sr_distance >= (val - 1)) && ...
%                             (U_sr_distance <= (val + 1));
%                         if isSRwithinRange
%                             options = [options string(room)]; %with IR info
%                             %extended_IRs = [extended_IRs string(room) a]; %with extended IR options
%                         end
%                     end
                    %         if isfield(env.(room),SRHeight)
                    %
                    %
                    %         end
                end
            end
    
            %Format array to send to dropdown list
            % Delete the first element (first entry is always empty)
            options = options(1:end);
            %extended_IRs = extended_IRs(1:end);


            app.dropdown = string(options);
            app.envSelect.Items = app.dropdown;

            if isempty(options)
                app.ErrorLabel.Visible= 'on';
            end
        end
        
        function [time,amplitude] = audioToArray(app,filename)
            %Takes audio input and returns two arrays time and amplitude
            %axis
            [y,fs] = audioread(string(filename));
            y = y(:,1);
            dt = 1/fs;
            time = 0:dt:(length(y)*dt)-dt;
            amplitude = y;

        end
        
        function [h] = rir(app, fs, mic, n, r, rm, src)
            %RIR   Room Impulse Response.
            %   [h] = RIR(FS, MIC, N, R, RM, SRC) performs a room impulse
            %         response calculation by means of the mirror image method.
            %
            %      FS  = sample rate.
            %      MIC = row vector giving the x,y,z coordinates of
            %            the microphone.
            %      N   = The program will account for (2*N+1)^3 virtual sources
            %      R   = reflection coefficient for the walls, in general -1<R<1.
            %      RM  = row vector giving the dimensions of the room.
            %      SRC = row vector giving the x,y,z coordinates of
            %            the sound source.
            %   NOTES:
            %
            %   1) All distances are in meters.
            %   2) The output is scaled such that the largest value of the
            %      absolute value of the output vector is equal to one.

            %Version 3.4.2
            %Copyright © 2003 Stephen G. McGovern

            nn=-n:1:n;                            % Index for the sequence
            rms=nn+0.5-0.5*(-1).^nn;              % Part of equations 2,3,& 4
            srcs=(-1).^(nn);                      % part of equations 2,3,& 4
            xi=srcs*src(1)+rms*rm(1)-mic(1);      % Equation 2
            yj=srcs*src(2)+rms*rm(2)-mic(2);      % Equation 3
            zk=srcs*src(3)+rms*rm(3)-mic(3);      % Equation 4

            [i,j,k]=meshgrid(xi,yj,zk);           % convert vectors to 3D matrices
            d=sqrt(i.^2+j.^2+k.^2);               % Equation 5
            time=round(fs*d/343)+1;               % Similar to Equation 6

            [e,f,g]=meshgrid(nn, nn, nn);         % convert vectors to 3D matrices
            c=r.^(abs(e)+abs(f)+abs(g));          % Equation 9
            e=c./d;                               % Equivalent to Equation 10

            h=full(sparse(time(:),1,e(:)));       % Equivalent to equation 11
            h=h/max(abs(h));                      % Scale output
        end
        
        function [y] = fconv(app,x,h)
            %FCONV Fast Convolution
            %   [y] = FCONV(x, h) convolves x and h, and normalizes the output
            %         to +-1.
            %
            %      x = input vector
            %      h = input vector
            %
            %      See also CONV
            %
            %   NOTES:
            %
            %   1) I have a short article explaining what a convolution is.  It
            %      is available at http://stevem.us/fconv.html.
            %
            %
            %Version 1.0
            %Coded by: Stephen G. McGovern, 2003-2004.

            Ly = length(x) + length(h) - 1;		%
            Ly2 = pow2(nextpow2(Ly));			% Find smallest power of 2 that is > Ly
            X = fft(x, Ly2);					% Fast Fourier transform
            H = fft(h, Ly2);					% Fast Fourier transform
            Y = X .* H;							%
            y = real(ifft(Y, Ly2));				% Inverse fast Fourier transform
            y = y(1:1:Ly);						% Take just the first N elements
            y=y/max(abs(y));					% Normalize the output
            
        end
        
        function updateEntries(app)
            value = app.envSelect.Value;
            switch value
                case {"nashville", app.all_options(1)}
                    app.Image2.ImageSource = "assets\1st-baptist-nashville\images\first_baptist_church_of_nashville_northwest_corner_2.jpg";
                    app.IRSelectDropDown.Items = ["Balcony" "Far" "Wide"];
                    switch app.IRSelectDropDown.Value
                        case "Balcony"
                            [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_nashville_balcony.wav");
                            plot(app.IRgraph,time,app.IR);
                        case "Far"
                            [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_far_close.wav");
                            plot(app.IRgraph,time,app.IR);
                        case "Wide"
                            [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_far_wide.wav");
                            plot(app.IRgraph,time,app.IR);
                    end
                case {"elveden_hall", app.all_options(2)}
                    app.Image2.ImageSource = "assets\elveden-hall-suffolk-england\images\elvedenlocationb.jpg";
                    app.IRSelectDropDown.Items = ["Marble" "Lord Cloaks" "Visitor Cloaks" "Smoking Room"];
                        switch app.IRSelectDropDown.Value
                            case "Marble"
                                [time,app.IR] = audioToArray(app,"assets\elveden-hall-suffolk-england\stereo\1a_marble_hall.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "Lord Cloaks"
                                [time,app.IR] = audioToArray(app,"assets\elveden-hall-suffolk-england\stereo\3a_hats_cloaks_the_lord.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "Visitor Cloaks"
                                [time,app.IR] = audioToArray(app,"assets\elveden-hall-suffolk-england\stereo\4a_hats_cloaks_visitors.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "Smoking Room"
                                [time,app.IR] = audioToArray(app,"assets\elveden-hall-suffolk-england\stereo\18a_smoking_room.wav"); 
                                plot(app.IRgraph,time,app.IR);
                        end
                case {"hamilton", app.all_options(3)}
                    app.IRSelectDropDown.Items = ["Main Chamber"];
                    app.Image2.ImageSource = "assets\hamilton-mausoleum\images\hm_int_sm.jpg";
                    [time,app.IR] = audioToArray(app,"assets\hamilton-mausoleum\stereo\hm2_000_ortf_48k.wav");
                    plot(app.IRgraph,time,app.IR);
                case {"heslington", app.all_options(4)}
                    app.Image2.ImageSource = "assets\heslington-church-vaa-group-2\images\heslington_church_impulse_response-4900.jpg";
                    app.IRSelectDropDown.Items = ["01" "02" "03" "04" "05" "06" "07"];
                    switch app.IRSelectDropDown.Value
                            case "01"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-001.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "02"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-002.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "03"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-003.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "04"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-004.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "05"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-005.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "06"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-006.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "07"
                                [time,app.IR] = audioToArray(app,"assets\heslington-church-vaa-group-2\b-format\impulseresponseheslingtonchurch-007.wav");
                                plot(app.IRgraph,time,app.IR);
                    end
                case {"maes_howe", app.all_options(5)}
                    app.Image2.ImageSource = "assets\maes-howe\images\mh_ext_sm.jpg";
                    app.IRSelectDropDown.Items = ["Center"];
                    [time,app.IR] = audioToArray(app,"assets\maes-howe\stereo\mh3_000_ortf_48k.wav");
                    plot(app.IRgraph,time,app.IR);
                case {"falkland_dungeon", app.all_options(6)}
                    app.Image2.ImageSource = "assets\falkland-palace-bottle-dungeon\images\dungeon.jpeg";
                    app.IRSelectDropDown.Items = ["Bottle"];
                    [time,app.IR] = audioToArray(app,"assets\falkland-palace-bottle-dungeon\b-format\bottledungeon1_sf_edited.wav");
                    plot(app.IRgraph,time,app.IR);
                case {"gill_heads_mine", app.all_options(7)}
                    app.Image2.ImageSource = "assets\gill-heads-mine\images\mine_entrance_b.jpg";
                    app.IRSelectDropDown.Items = ["Site One 1 Way" "Site One - 2 way" "Site Two - 1 Way" "Site Two - 2 Way"];
                    switch app.IRSelectDropDown.Value
                        case "Site One 1 Way"
                            [time,app.IR] = audioToArray(app,"assets\gill-heads-mine\b-format\mine_site1_1way_bformat.wav");
                            plot(app.IRgraph,time,app.IR);
                        case "Site One - 2 way"
                            [time,app.IR] = audioToArray(app,"assets\gill-heads-mine\b-format\mine_site1_2way_bformat.wav");
                            plot(app.IRgraph,time,app.IR);
                        case "Site Two - 1 Way"
                            [time,app.IR] = audioToArray(app,"assets\gill-heads-mine\b-format\mine_site2_1way_bformat.wav");
                            plot(app.IRgraph,time,app.IR);
                        case "Site Two - 2 Way"
                            [time,app.IR] = audioToArray(app,"assets\gill-heads-mine\b-format\mine_site2_2way_bformat.wav");
                            plot(app.IRgraph,time,app.IR);
                    end
                case {"hoffmann", app.all_options(8)}
                    app.Image2.ImageSource = "assets\hoffmann-lime-kiln-langcliffeuk\images\AdobeStock_118983058-scaled.jpeg";
                    app.IRSelectDropDown.Items = ["p1" "p2" "p3" "p4" "p5" "p6"];
                    switch app.IRSelectDropDown.Value
                            case "p1"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "p2"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p2.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "p3"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p3.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "p4"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p4.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "p5"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p5.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "p6"
                                [time,app.IR] = audioToArray(app,"assets\hoffmann-lime-kiln-langcliffeuk\b-format\ir_p6.wav");
                                plot(app.IRgraph,time,app.IR);
                    end
                case {"newgrange", app.all_options(9)}
                    app.Image2.ImageSource = "assets\newgrange\images\structure.jpg";
                    app.IRSelectDropDown.Items = ["s1" "s2" "s3" "s4" "s5" "s6"];
                    switch app.IRSelectDropDown.Value
                            case "s1"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s1r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s2"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s2r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s3"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s3r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s4"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s4r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s5"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s5r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s6"
                                [time,app.IR] = audioToArray(app,"assets\newgrange\b-format\newgrange_s6r1.wav");
                                plot(app.IRgraph,time,app.IR);
                    end
                  case {"patrington", app.all_options(10)}
                    app.Image2.ImageSource = "assets\st-patricks-church-patrington\images\sl381519.jpg";
                    app.IRSelectDropDown.Items = ["s1" "s2" "s3"];
                    switch app.IRSelectDropDown.Value
                            case "s1"
                                [time,app.IR] = audioToArray(app,"assets\st-patricks-church-patrington\b-format\soundfield_s1r1.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s2"
                                [time,app.IR] = audioToArray(app,"assets\st-patricks-church-patrington\b-format\soundfield_s2r2.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "s3"
                                [time,app.IR] = audioToArray(app,"assets\st-patricks-church-patrington\b-format\soundfield_s3r3.wav");
                                plot(app.IRgraph,time,app.IR);
                    end
                 case {"trollers_gill", app.all_options(11)}
                    app.Image2.ImageSource = "assets\trollers-gill\images\site1_b.jpg";
                    app.IRSelectDropDown.Items = ["site 1" "site 2" "site 3"];
                    switch app.IRSelectDropDown.Value
                        case "site 1"
                                [time,app.IR] = audioToArray(app,"assets\trollers-gill\b-format\dales_site1_4way_bformat.wav");
                                plot(app.IRgraph,time,app.IR);
                        case "site 2"
                                [time,app.IR] = audioToArray(app,"assets\trollers-gill\b-format\dales_site2_4way_bformat.wav");
                                plot(app.IRgraph,time,app.IR);
                        case "site 3"
                                [time,app.IR] = audioToArray(app,"assets\trollers-gill\b-format\dales_site3_4way_bformat.wav");
                                plot(app.IRgraph,time,app.IR);
                    end
                case {"tyndall_bruce", app.all_options(12)}
                    app.Image2.ImageSource = "assets\tyndall-bruce-monument\images\dsc00532.jpg";
                    app.IRSelectDropDown.Items = ["tyndall"];
                
                    [time,app.IR] = audioToArray(app,"assets\tyndall-bruce-monument\b-format\tyndall_bruce_b_format.wav");
                    plot(app.IRgraph,time,app.IR);
                case {"usina_del_arte", app.all_options(12)}
                    app.Image2.ImageSource = "assets\usina-del-arte-symphony-hall\images\usina_full.jpg";
                    app.IRSelectDropDown.Items = ["w" "x" "y" "z"];
                    switch app.IRSelectDropDown.Value
                        case "w"
                                [time,app.IR] = audioToArray(app,"assets\usina-del-arte-symphony-hall\b-format\ir_w_m5.s1.wav");
                                plot(app.IRgraph,time,app.IR);
                        case "x"
                                [time,app.IR] = audioToArray(app,"assets\usina-del-arte-symphony-hall\b-format\ir_x_m5.s1.wav");
                                plot(app.IRgraph,time,app.IR);
                        case "y"
                                [time,app.IR] = audioToArray(app,"assets\usina-del-arte-symphony-hall\b-format\ir_y_m5.s1.wav");
                                plot(app.IRgraph,time,app.IR);
                        case "z"
                                [time,app.IR] = audioToArray(app,"assets\usina-del-arte-symphony-hall\b-format\ir_z_m5.s1.wav");
                                plot(app.IRgraph,time,app.IR);
                    end    

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
    
            %Write data into properties
            Width = app.WidthSlider.Value;
            Depth = app.DepthSlider.Value;
            Height = app.HeightSlider.Value;
            
            sourceCoord = [app.sourceXSpinner.Value, app.sourceYSpinner.Value, app.sourceZSpinner.Value];
            receiverCoord = [app.recXSpinner.Value,app.recYSpinner.Value,app.recZSpinner.Value];
            
            %app.sourceCoords = sourceCoord;
            %app.recieverCoords = receiverCoord;

            dist = norm(receiverCoord - sourceCoord);

            %Creating room Dimensions array
            roomDimensions = [Width Depth Height];
            X = [0;roomDimensions(1);roomDimensions(1);0;0];
            Y = [0;0;roomDimensions(2);roomDimensions(2);0];
            Z = [0;0;0;0;0];
            
            hold(app.UIAxes,'on');
            % draw a square in the xy plane with z = 0
            plot3(app.UIAxes,X,Y,Z,"k","LineWidth",1.5);
            % draw a square in the xy plane with z = 1
            plot3(app.UIAxes,X,Y,Z+roomDimensions(3),"k","LineWidth",1.5); 
            %set(app.UIAxes,gca,"View",[-28,35]); % set the azimuth and elevation of the plot
            for k=1:length(X)-1
                plot3(app.UIAxes, [X(k);X(k)],[Y(k);Y(k)],[0;roomDimensions(3)],"k","LineWidth",1.5);
            end
            plot3(app.UIAxes,sourceCoord(1),sourceCoord(2),sourceCoord(3),"bx","LineWidth",2)
            plot3(app.UIAxes,receiverCoord(1),receiverCoord(2),receiverCoord(3),"ro","LineWidth",2)

            hold(app.UIAxes,'off');
            %plot3(app.UIAxes,Width,Height,Depth,'.')

            %Update options list based on user input
            returnOptions(app,Width,Depth,Height,dist);
            updateEntries(app);
            %Generate Room Impulse Response
            app.RIR = rir(app,app.fs_file,receiverCoord, 6 ,0.75 ,roomDimensions,sourceCoord);

            
        end

        % Value changed function: SelectModeListBox
        function SelectModeListBoxValueChanged(app, event)
            value = app.SelectModeListBox.Value;
  
            switch value
                case "Suggestion Mode"
                    
                    app.envSelect.Items = app.dropdown;
                    updateEntries(app);
                
                case "All Options"
                    % update the selection dropdown
                    app.envSelect.Items = app.all_options; 
                    value1 = app.envSelect.Value;
                    switch value1 %edge case first option
                        case {"nashville", app.all_options(1)}
                            app.Image2.ImageSource = "assets\1st-baptist-nashville\images\first_baptist_church_of_nashville_northwest_corner_2.jpg";
                        app.IRSelectDropDown.Items = ["Balcony" "Far" "Wide"];
                        switch app.IRSelectDropDown.Value
                            case "Balcony"
                                [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_nashville_balcony.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "Far"
                                [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_far_close.wav");
                                plot(app.IRgraph,time,app.IR);
                            case "Wide"
                                [time,app.IR] = audioToArray(app,"assets\1st-baptist-nashville\stereo\1st_baptist_far_wide.wav");
                                plot(app.IRgraph,time,app.IR);
                        end
                    end
                
            end
        end

        % Value changed function: envSelect
        function envSelectValueChanged(app, event)
            updateEntries(app);
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [file, path] = uigetfile('*.wav'); %open a mp3 file
            app.FileNameEditField.Value = string(file);
            figure(app.UIFigure);
            [output,fs] = audioread(file);
            app.audioFile = output(:,1);
            dt = 1/fs;
            app.fs_file = fs;
            t = 0:dt:(length(app.audioFile)*dt)-dt;
            plot(app.UIAxes2,t,app.audioFile)

             
        end

        % Button pushed function: ApplyIRButton
        function ApplyIRButtonPushed(app, event)
            if isempty(app.RIR) == 1 %If RIR is empty just use IR
                output = conv(app.IR, app.audioFile);
                dt = 1/app.fs_file; 
                t = 0:dt:(length(output)*dt)-dt;
    
                plot(app.OutputGraph,t,output);
                app.applied_IR = output;
                
            elseif isempty(app.IR) ~= 1 % Using both RIR and IR (Full)

                app.ErrorLabel_IR.Visible = "off";

                output1 = fconv(app,app.audioFile,app.RIR);

                output = conv(app.IR, output1);
                dt = 1/app.fs_file; 
                t = 0:dt:(length(output)*dt)-dt;
    
                plot(app.OutputGraph,t,output);
                app.applied_IR = output;
            else
                app.ErrorLabel_IR.Visible = "on";
            end

            
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            %app.applied_IR = pl
            
            soundsc(app.applied_IR,app.fs_file)
        end

        % Button pushed function: PlaySample
        function PlaySampleButtonPushed(app, event)
            if isempty(app.audioFile) ~= 1
                soundsc(app.audioFile,app.fs_file)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcomePanel = uipanel(app.HomeTab);
            app.WelcomePanel.Title = 'Welcome';
            app.Image = uiimage(app.WelcomePanel);
            app.Image.ImageSource = 'Dawn-over-the-Minack-1-1.jpg';
            app.AcousticSimulatorLabel = uilabel(app.WelcomePanel);
            app.AcousticSimulatorLabel.Text = ' Acoustic Simulator';
            app.InstructionsPanel = uipanel(app.HomeTab);
            app.InstructionsPanel.Title = 'Instructions';
            app.ThisisanacousticroomsimulatorusingOpenAIRIRLabel = uilabel(app.InstructionsPanel);
            app.ThisisanacousticroomsimulatorusingOpenAIRIRLabel.Text = 'This is an acoustic room simulator using Open AIR IR. ';
            app.DirectionsLabel = uilabel(app.InstructionsPanel);
            app.DirectionsLabel.Text = 'Directions';
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel = uilabel(app.InstructionsPanel);
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel.Text = '1. Set desired size of enviornemnt in Configure panel, and click simulate';
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_2 = uilabel(app.InstructionsPanel);
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_2.Text = '2. Select desired enviornement stage (suggestions will be populated based on user selection in Configuration)';
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_3 = uilabel(app.InstructionsPanel);
            app.SetdesiredsizeofenviornemntinConfigurepanelandclickLabel_3.Text = '3. Simulate the enviornemnt with a chosen audio file by clicking simulate!';
            app.Hyperlink = uihyperlink(app.HomeTab);
            app.Hyperlink.URL = 'https://www.openairlib.net/';
            app.Hyperlink.Text = 'Open AIR ';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.UIAxes = uiaxes(app.ConfigureTab);
            title(app.UIAxes, 'Room Model')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z (m)')
            app.UIAxes.XLim = [-100 100];
            app.UIAxes.YLim = [-100 100];
            app.UIAxes.ZLim = [-100 100];
            app.ParametersPanel = uipanel(app.ConfigureTab);
            app.ParametersPanel.Title = 'Parameters';
            app.RoomSizeParametersPanel = uipanel(app.ConfigureTab);
            app.RoomSizeParametersPanel.Title = 'Room Size Parameters';
            app.WidthSlider = uislider(app.RoomSizeParametersPanel);
            app.WidthSlider_2Label = uilabel(app.RoomSizeParametersPanel);
            app.WidthSlider_2Label.Text = 'Width';
            app.DepthSlider_2Label = uilabel(app.RoomSizeParametersPanel);
            app.DepthSlider_2Label.Text = 'Depth';
            app.DepthSlider = uislider(app.RoomSizeParametersPanel);
            app.HeightSliderLabel = uilabel(app.RoomSizeParametersPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.RoomSizeParametersPanel);
            app.SourceRecieverCoordinatesPanel = uipanel(app.ConfigureTab);
            app.SourceRecieverCoordinatesPanel.Title = 'Source & Reciever Coordinates';
            app.SourcexLabel = uilabel(app.SourceRecieverCoordinatesPanel);
            app.SourcexLabel.Text = 'Source ''x''';
            app.RecieveroLabel = uilabel(app.SourceRecieverCoordinatesPanel);
            app.RecieveroLabel.Text = 'Reciever ''o''';
            app.XSpinnerLabel = uilabel(app.SourceRecieverCoordinatesPanel);
            app.XSpinnerLabel.Text = 'X';
            app.sourceXSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.sourceXSpinner.Limits = [0 100];
            app.YSpinnerLabel = uilabel(app.SourceRecieverCoordinatesPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.sourceYSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.sourceYSpinner.Limits = [0 100];
            app.ZSpinnerLabel = uilabel(app.SourceRecieverCoordinatesPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.sourceZSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.sourceZSpinner.Limits = [0 100];
            app.XSpinner_2Label = uilabel(app.SourceRecieverCoordinatesPanel);
            app.XSpinner_2Label.Text = 'X';
            app.recXSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.recXSpinner.Limits = [0 100];
            app.ZSpinner_2Label = uilabel(app.SourceRecieverCoordinatesPanel);
            app.ZSpinner_2Label.Text = 'Z';
            app.recZSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.recZSpinner.Limits = [0 100];
            app.YSpinner_2Label = uilabel(app.SourceRecieverCoordinatesPanel);
            app.YSpinner_2Label.Text = 'Y';
            app.recYSpinner = uispinner(app.SourceRecieverCoordinatesPanel);
            app.recYSpinner.Limits = [0 100];
            app.SimulateButton = uibutton(app.ConfigureTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ReflectionCoeffecientKnobLabel = uilabel(app.ConfigureTab);
            app.ReflectionCoeffecientKnobLabel.Text = 'Reflection Coeffecient';
            app.ReflectionCoeffecientKnob = uiknob(app.ConfigureTab, 'continuous');
            app.ReflectionCoeffecientKnob.Limits = [-1 1];
            app.TailCutoffKnobLabel = uilabel(app.ConfigureTab);
            app.TailCutoffKnobLabel.Text = 'Tail Cutoff';
            app.TailCutoffKnob = uiknob(app.ConfigureTab, 'continuous');
            app.TailCutoffKnob.Limits = [1 16];
            app.TailCutoffKnob.Value = 1;
            app.SelectionTab = uitab(app.TabGroup);
            app.SelectionTab.Title = 'Selection';
            app.IRgraph = uiaxes(app.SelectionTab);
            title(app.IRgraph, 'Loaded IR Response')
            xlabel(app.IRgraph, 'Time')
            ylabel(app.IRgraph, 'Y')
            zlabel(app.IRgraph, 'Z')
            app.ChooseEnviornmentPanel = uipanel(app.SelectionTab);
            app.ChooseEnviornmentPanel.Title = 'Choose Enviornment';
            app.EnviornementSelectDropDownLabel = uilabel(app.ChooseEnviornmentPanel);
            app.EnviornementSelectDropDownLabel.Text = 'Enviornement Select';
            app.envSelect = uidropdown(app.ChooseEnviornmentPanel);
            app.envSelect.Items = {};
            app.envSelect.ValueChangedFcn = createCallbackFcn(app, @envSelectValueChanged, true);
            app.envSelect.Value = {};
            app.SelectModeListBoxLabel = uilabel(app.ChooseEnviornmentPanel);
            app.SelectModeListBoxLabel.Text = 'Select Mode';
            app.SelectModeListBox = uilistbox(app.ChooseEnviornmentPanel);
            app.SelectModeListBox.Items = {'Suggestion Mode', 'All Options'};
            app.SelectModeListBox.ValueChangedFcn = createCallbackFcn(app, @SelectModeListBoxValueChanged, true);
            app.SelectModeListBox.Value = 'Suggestion Mode';
            app.ErrorLabel = uilabel(app.ChooseEnviornmentPanel);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'No IR match user configuration';
            app.IRSelectDropDownLabel = uilabel(app.ChooseEnviornmentPanel);
            app.IRSelectDropDownLabel.Text = 'IR Select';
            app.IRSelectDropDown = uidropdown(app.ChooseEnviornmentPanel);
            app.IRSelectDropDown.Items = {};
            app.IRSelectDropDown.Value = {};
            app.Image2 = uiimage(app.SelectionTab);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.LoadAudioFilePanel = uipanel(app.SimulationTab);
            app.LoadAudioFilePanel.Title = 'Load Audio File';
            app.UIAxes2 = uiaxes(app.LoadAudioFilePanel);
            title(app.UIAxes2, 'Selected Audio File')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.FileNameEditFieldLabel = uilabel(app.LoadAudioFilePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadAudioFilePanel, 'text');
            app.LoadAudioButton = uibutton(app.LoadAudioFilePanel, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.PlaySample = uibutton(app.LoadAudioFilePanel, 'push');
            app.PlaySample.ButtonPushedFcn = createCallbackFcn(app, @PlaySampleButtonPushed, true);
            app.PlaySample.Text = 'Play Audio';
            app.SimulatePanel = uipanel(app.SimulationTab);
            app.SimulatePanel.Title = 'Simulate';
            app.OutputGraph = uiaxes(app.SimulatePanel);
            title(app.OutputGraph, 'Output')
            xlabel(app.OutputGraph, 'Time')
            ylabel(app.OutputGraph, 'Y')
            zlabel(app.OutputGraph, 'Z')
            app.ApplyIRButton = uibutton(app.SimulatePanel, 'push');
            app.ApplyIRButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyIRButtonPushed, true);
            app.ApplyIRButton.Text = 'Apply IR';
            app.ResetButton = uibutton(app.SimulatePanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.PlayAudioButton = uibutton(app.SimulatePanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.ErrorLabel_IR = uilabel(app.SimulatePanel);
            app.ErrorLabel_IR.Visible = 'off';
            app.ErrorLabel_IR.Text = 'No IR selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoomAcoustic_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end